classdef ReconSlideDEM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AwaitingrunLabel                matlab.ui.control.Label
        HelpButton                      matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        Lamp                            matlab.ui.control.Lamp
        LoadbathymetryfileButton        matlab.ui.control.Button
        LoadscarshapefileButton         matlab.ui.control.Button
        YmaxLabel                       matlab.ui.control.Label
        LoadsmoothzoneshapefileButton   matlab.ui.control.Button
        DEMresolutionEditField          matlab.ui.control.NumericEditField
        GuasscoreEditField              matlab.ui.control.NumericEditField
        ContourlevelsintervalEditField  matlab.ui.control.NumericEditField
        OutputgridsizeEditField         matlab.ui.control.NumericEditField
        YmaxEditField                   matlab.ui.control.NumericEditField
        YminEditField                   matlab.ui.control.NumericEditField
        YminLabel                       matlab.ui.control.Label
        XmaxEditField                   matlab.ui.control.NumericEditField
        XmaxEditFieldLabel              matlab.ui.control.Label
        XminEditField                   matlab.ui.control.NumericEditField
        XminEditFieldLabel              matlab.ui.control.Label
        SelectoutputpathButton          matlab.ui.control.Button
        DEMresolutionEditFieldLabel     matlab.ui.control.Label
        OutputgridsizeEditFieldLabel    matlab.ui.control.Label
        ContourlevelsintervalEditFieldLabel  matlab.ui.control.Label
        GuasscoreEditFieldLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
        BATHYFile
        SCARFile
        SMOOTHZoneFile
        outputPath
        tStart
        tName
    end
    
    
    methods (Access = private)
        
        function StartProcessing(app)
            app.tStart = tic;
            app.tName = char(datetime('now', 'Format', 'yyyyMMdd_HHmmss'));
            
            % Parameters
            OrigindataResolution = app.DEMresolutionEditField.Value;
            gausscore = app.GuasscoreEditField.Value;
            contour_levels_inter = app.ContourlevelsintervalEditField.Value;
            width = app.OutputgridsizeEditField.Value;
            x_area_min = app.XminEditField.Value;
            x_area_max = app.XmaxEditField.Value;
            y_area_min = app.YminEditField.Value;
            y_area_max = app.YmaxEditField.Value;
            
            % Landslide Reconstruction
            [X, Y, Z, reconstructedZ] = reconLandslideDEM(OrigindataResolution, gausscore, app.SCARFile, app.SMOOTHZoneFile, app.BATHYFile);

            % Grid Setup for Visualization
            AdjustX = min(X);
            AdjustY = min(Y);
            X = X - AdjustX;
            Y = Y - AdjustY;
            Z = Z - min(Z);
            [xq, yq] = meshgrid(x_area_min:width:x_area_max, y_area_min:width:y_area_max);
            vq_reconstructed = griddata(X, Y, reconstructedZ, xq, yq);
            vq_original = griddata(X, Y, Z, xq, yq);

            % Plot Reconstructed Slide
            f1 = figure;
            set(f1, 'Units', 'centimeters');
            set(f1, 'Position', [10 10 16 10]); 
            set(f1, 'PaperUnits', 'centimeters');
            set(f1, 'PaperPosition', [0 0 16 10]);

            ZData = vq_reconstructed;
            featureData = vq_reconstructed;

            surf(xq, yq, ZData, 'CData', featureData, 'FaceColor', 'interp', 'EdgeColor', 'none');
            colormap("parula");
            clim([min(featureData(:)), max(featureData(:))]);
            cb = colorbar;
            ylabel(cb, 'Evaluation (m)');
            hold on;

            contour_levels = floor(min(vq_reconstructed(:))):contour_levels_inter:ceil(max(vq_reconstructed(:)));
            contour3(xq, yq, ZData, contour_levels, 'LineColor', 'k', 'LineWidth', 0.3, 'EdgeAlpha', 0.8);
            ScarShapedata = readmatrix(app.SCARFile);
            scarX = ScarShapedata(:, 1) - AdjustX;
            scarY = ScarShapedata(:, 2) - AdjustY;
            scarZ = max(vq_reconstructed(:)) * ones(size(scarY));
            scarX = [scarX; scarX(1)];
            scarY = [scarY; scarY(1)];
            scarZ = [scarZ; scarZ(1)];

            plot3(scarX, scarY, scarZ, 'Color', [0.8500, 0.3250, 0.0980], 'LineWidth', 2);

            xlabel('X (m)');
            ylabel('Y (m)');
            zlabel('Height (m)');
            xlim([x_area_min, x_area_max]);
            ylim([y_area_min, y_area_max]);
            title('Reconstructed Slide');
            material dull;
            shading flat;
            daspect([1 1 1]);
            view([0 0 1]);

            % Export the reconstructed slide visualization
            exportgraphics(f1, fullfile(app.outputPath, [app.tName '_ReconstructedMap.png']), 'Resolution', 600);
            % close(f1);

            % Plot Original Slide
            f2 = figure;
            set(f2, 'Units', 'centimeters');
            set(f2, 'Position', [10 10 16 10]); 
            set(f2, 'PaperUnits', 'centimeters');
            set(f2, 'PaperPosition', [0 0 16 10]);

            ZData = vq_original;
            featureData = vq_original;

            surf(xq, yq, ZData, 'CData', featureData, 'FaceColor', 'interp', 'EdgeColor', 'none');
            colormap("parula");
            clim([min(featureData(:)), max(featureData(:))]);
            cb = colorbar;
            ylabel(cb, 'Evaluation (m)');
            hold on;

            contour_levels = floor(min(vq_original(:))):contour_levels_inter:ceil(max(vq_original(:)));
            contour3(xq, yq, ZData, contour_levels, 'LineColor', 'k', 'LineWidth', 0.3, 'EdgeAlpha', 0.8);

            plot3(scarX, scarY, scarZ, 'Color', [0.8500, 0.3250, 0.0980], 'LineWidth', 2);

            xlabel('X (m)');
            ylabel('Y (m)');
            zlabel('Height (m)');
            xlim([x_area_min, x_area_max]);
            ylim([y_area_min, y_area_max]);
            title('Original Slide');
            material dull;
            shading flat;
            daspect([1 1 1]);
            view([0 0 1]);

            % Export the original slide visualization
            exportgraphics(f2, fullfile(app.outputPath, [app.tName '_OriginalMap.png']), 'Resolution', 600);
            % close(f2);

            % Save results
            fileID = fopen(fullfile(app.outputPath, [app.tName 'ReconXYZ.dat']), 'wt'); 
            x = xq(:);
            y = yq(:);
            z = vq_reconstructed(:);
            [nrows, ~] = size(x);
            for row = 1:nrows
                fprintf(fileID, '%f %f %f', x(row), y(row), z(row)); 
                fprintf(fileID, '\n');
            end
            fclose(fileID); 
            
            % Timing Information
            tEnd = toc(app.tStart);
            formattedTime = formatTime(tEnd);
            disp(['Reconstruction done! Output has been saved to ', app.outputPath]);
            disp(['Elapsed time: ', formattedTime]);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadbathymetryfileButton
        function LoadbathymetryfileButtonPushed(app, event)
             [file, path] = uigetfile('*.dat', 'Select Bathymetry File');
            if isequal(file, 0)
                disp('User selected Cancel');
            else
                app.BATHYFile = fullfile(path, file);
                disp (['Loaded Bathymetry File: ', file]);
            end
        end

        % Button pushed function: LoadsmoothzoneshapefileButton
        function LoadsmoothzoneshapefileButtonPushed(app, event)
           [file, path] = uigetfile('*.dat', 'Select Smooth Zone Shape File');
            if isequal(file, 0)
                disp('User selected Cancel');
            else
                app.SMOOTHZoneFile = fullfile(path, file);
                disp(['Loaded Smooth Zone Shape File: ' file]);
            end
        end

        % Button pushed function: LoadscarshapefileButton
        function LoadscarshapefileButtonPushed(app, event)
            [file, path] = uigetfile('*.dat', 'Select Scar Shape File');
            if isequal(file, 0)
                disp('User selected Cancel');
            else
                app.SCARFile = fullfile(path, file);
                disp(['Loaded Scar Shape File: ', file]);
            end
        end

        % Button pushed function: SelectoutputpathButton
        function SelectoutputpathButtonPushed(app, event)
            path = uigetdir;
            if isequal(path, 0)
                disp('User selected Cancel');
            else
                app.outputPath = path;
                disp(['Selected Output Path: ', path]);
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)

            % Check that all required files have been loaded
            if isempty(app.SCARFile) || isempty(app.SMOOTHZoneFile) || isempty(app.BATHYFile)|| isempty(app.outputPath)
                uialert(app.UIFigure, 'Please load all required files before starting the process.', 'Missing File');
                return;
            end

            app.Lamp.Color = [0.85,0.33,0.10];
            app.AwaitingrunLabel.Text = 'Running...';
            drawnow;

            try

                StartProcessing(app)

                app.Lamp.Color = [0.39,0.83,0.07];
                app.AwaitingrunLabel.Text = 'Completed';
            catch ME
                % In case of an error, set the lamp to yellow and display an error message
                app.Lamp.Color = 'yellow';
                app.AwaitingrunLabel.Text = 'Error';
                rethrow(ME); % Optionally rethrow the error to see the full error message
            end
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            % Example explanation text for DiscrebstionTextArea
            explanationText = sprintf(['Input Parameters Explanation:\n\n', ...
                '1. DEM Resolution: The resolution of the Digital Elevation Model (DEM) grid. ', ...
                'A smaller value increases the grid density, leading to finer details in the reconstruction but may increase processing time.\n\n', ...
                '2. Gaussian Core: The standard deviation of the Gaussian kernel used for smoothing. ', ...
                'Higher values produce smoother results but can remove small features in the terrain.\n\n', ...
                '3. Contour Levels Interval: The spacing between contour lines in the output visualizations. ', ...
                'Smaller intervals provide more contour lines, giving more detail, while larger intervals simplify the map.\n\n', ...
                '4. Output Grid Size: The size of the grid cells in the output. ', ...
                'This determines the resolution of the final visualized grid. Larger grid sizes reduce detail but decrease file size and processing time.\n\n', ...
                '5. X Min & X Max: Define the horizontal bounds of the area of interest. ', ...
                'These values limit the extent of the analysis in the X direction.\n\n', ...
                '6. Y Min & Y Max: Define the vertical bounds of the area of interest. ', ...
                'These values limit the extent of the analysis in the Y direction.\n\n']);
            % disp(explanationText);
            fprintf('%s\n', explanationText);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GuasscoreEditFieldLabel = uilabel(app.UIFigure);
            app.GuasscoreEditFieldLabel.Text = 'Guass core';
            app.ContourlevelsintervalEditFieldLabel = uilabel(app.UIFigure);
            app.ContourlevelsintervalEditFieldLabel.Text = 'Contour levels interval';
            app.OutputgridsizeEditFieldLabel = uilabel(app.UIFigure);
            app.OutputgridsizeEditFieldLabel.Text = 'Output grid size';
            app.DEMresolutionEditFieldLabel = uilabel(app.UIFigure);
            app.DEMresolutionEditFieldLabel.Text = 'DEM resolution';
            app.SelectoutputpathButton = uibutton(app.UIFigure, 'push');
            app.SelectoutputpathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectoutputpathButtonPushed, true);
            app.SelectoutputpathButton.Text = 'Select output path';
            app.XminEditFieldLabel = uilabel(app.UIFigure);
            app.XminEditFieldLabel.Text = 'X min';
            app.XminEditField = uieditfield(app.UIFigure, 'numeric');
            app.XmaxEditFieldLabel = uilabel(app.UIFigure);
            app.XmaxEditFieldLabel.Text = 'X max';
            app.XmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.YminLabel = uilabel(app.UIFigure);
            app.YminLabel.Text = 'Y min';
            app.YminEditField = uieditfield(app.UIFigure, 'numeric');
            app.YmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.OutputgridsizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.ContourlevelsintervalEditField = uieditfield(app.UIFigure, 'numeric');
            app.GuasscoreEditField = uieditfield(app.UIFigure, 'numeric');
            app.DEMresolutionEditField = uieditfield(app.UIFigure, 'numeric');
            app.LoadsmoothzoneshapefileButton = uibutton(app.UIFigure, 'push');
            app.LoadsmoothzoneshapefileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadsmoothzoneshapefileButtonPushed, true);
            app.LoadsmoothzoneshapefileButton.Text = 'Load smooth zone shape file';
            app.YmaxLabel = uilabel(app.UIFigure);
            app.YmaxLabel.Text = 'Y max';
            app.LoadscarshapefileButton = uibutton(app.UIFigure, 'push');
            app.LoadscarshapefileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadscarshapefileButtonPushed, true);
            app.LoadscarshapefileButton.Text = 'Load scar shape file ';
            app.LoadbathymetryfileButton = uibutton(app.UIFigure, 'push');
            app.LoadbathymetryfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadbathymetryfileButtonPushed, true);
            app.LoadbathymetryfileButton.Text = 'Load bathymetry file ';
            app.Lamp = uilamp(app.UIFigure);
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Icon = fullfile(pathToMLAPP, 'runicon.png');
            app.RunButton.Text = 'Run!';
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.AwaitingrunLabel = uilabel(app.UIFigure);
            app.AwaitingrunLabel.Text = 'Awaiting run';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ReconSlideDEM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end